classdef PhosphoEnrichment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        WelcometoPhosphoEnrichmentLabel  matlab.ui.control.Label
        ThissoftwareisdevelopedtofaciliatateLabel  matlab.ui.control.Label
        PathwayEnrichmentAnalysisLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        significantsitesorsitesintheidentifiedmodulesetcLabel  matlab.ui.control.Label
        KinaseSubstrateEnrichmentAnalysisLabel  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        ContactLabel                    matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        identifiedmodulesetcLabel       matlab.ui.control.Label
        modulesetcLabel                 matlab.ui.control.Label
        marziehayatiutrgveduLabel       matlab.ui.control.Label
        PathwayEnrichmentAnalysisTab    matlab.ui.container.Tab
        TargetPhosphositesProteinsTextAreaLabel  matlab.ui.control.Label
        TargetPhosphositesProteinsTextArea  matlab.ui.control.TextArea
        AllIdentifiedPhosphositespopulationTextAreaLabel  matlab.ui.control.Label
        AllIdentifiedPhosphositespopulationTextArea  matlab.ui.control.TextArea
        InputDataButtonGroup            matlab.ui.container.ButtonGroup
        PhosphositesButton              matlab.ui.control.RadioButton
        ProteinsButton                  matlab.ui.control.RadioButton
        DatabaseButtonGroup             matlab.ui.container.ButtonGroup
        PTMsigDBButton                  matlab.ui.control.RadioButton
        SigDBButton                     matlab.ui.control.RadioButton
        EnrichmentAnalysisButton        matlab.ui.control.Button
        ThedetailsofoverlapingproteinssiteswillbewritteninthefileLabel  matlab.ui.control.Label
        ResultPanel                     matlab.ui.container.Panel
        KinaseSubstrateEnrichmentAnalysisTab  matlab.ui.container.Tab
        AllPhosphoSiteslogFoldChangeTextAreaLabel  matlab.ui.control.Label
        AllPhosphoSiteslogFoldChangeTextArea  matlab.ui.control.TextArea
        TargetPhosphositesTextAreaLabel  matlab.ui.control.Label
        TargetPhosphositesTextArea      matlab.ui.control.TextArea
        RunKSEAButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TabdelimitedLabel               matlab.ui.control.Label
        TheresultwillbewritteninthefileLabel  matlab.ui.control.Label
        DataFormatTab                   matlab.ui.container.Tab
        PhosphositesLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MYOTY397Label                   matlab.ui.control.Label
        HIPK3S362Label                  matlab.ui.control.Label
        LogFoldChangeLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        MYOTT397215Label                matlab.ui.control.Label
        HIPK3S3983124Label              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnrichmentAnalysisButton
        function EnrichmentAnalysisButtonPushed(app, event)
            listofTargets = app.TargetPhosphositesProteinsTextArea.Value;
            listofTargets=unique(upper(listofTargets));
            
            listofAllPopulation = app.AllIdentifiedPhosphositespopulationTextArea.Value;
            listofAllPopulation = unique(upper(listofAllPopulation));
            flag = '';
            if(app.PTMsigDBButton.Value)
                sourcepathways =load('PTMsigDB','pathways_site'); 
                sourcepathways =sourcepathways.pathways_site;
                flag = 'PTMsigdb';
            end
            if(app.SigDBButton.Value)                
                 sourcepathways =load('PTMsigDB','pathways_genes2');
                 sourcepathways =sourcepathways.pathways_genes2;
                 if(app.ProteinsButton.Value)
                      listofTargets = unique(listofTargets);
                     listofAllPopulation = unique(listofAllPopulation);          
                 else
                     listofTargets = unique(genesNamesfromPhosphosites(listofTargets));
                     listofAllPopulation = unique(genesNamesfromPhosphosites(listofAllPopulation));                 
                 end
                 flag = 'sigdb';
            end
            for i=1:length(sourcepathways)   
                x = intersect(listofTargets,sourcepathways{i,2});
                if(length(x) > 1)
                    K = length(intersect(sourcepathways{i,2},listofAllPopulation));
                    N = length(listofTargets);
                    M = length(listofAllPopulation);
                    hypvalue(i) = hygecdf(length(x),M,K,N,'upper');
                    intersection{i} = x;
                    intersection_size(i) = length(x);
                else
                     hypvalue(i) = 1;
                      intersection{i} =0;
                      intersection_size(i) = 0;
                end   
            end
            
            [pvalue I] = sort(hypvalue,'ascend');
            pathway = sourcepathways(I);            
            intersection = intersection(I);
            intersection_size=intersection_size(I);
            exclude = find(pvalue == 1);            
            pathway(exclude)=[];
            pvalue(exclude)=[];
            intersection(exclude)=[];
            intersection_size(exclude)=[];
            tbl = table(pathway',pvalue',intersection','VariableNames',{'Pathway','Pvalue','Intersection'}); 
            writetable(tbl,strcat('Result_',flag,'_EnrichmentAnalysis'),'delimiter','\t','WriteVariableNames',true);  
                        
            exclude = find(pvalue == 0);
            pathway(exclude)=[];
            pvalue(exclude)=[];
            intersection(exclude)=[];
            intersection_size(exclude)=[];
            include = find(pvalue <= 0.11);
            pathway = pathway(include);
            pvalue = pvalue(include);      
            intersection_size = intersection_size(include);
            tbl = table(string(pathway'),pvalue',intersection_size','VariableNames',{'Pathway','Pvalue','Overlap'}); 
            %tb = app.UITable.Position;            
            %uitable(app.UIFigure, 'Data',tbl,'Position',tb);                     
            uitable(app.ResultPanel,'Data',tbl,'OuterPosition',app.ResultPanel.Position,'FontSize',14);
            
        end

        % Value changed function: TargetPhosphositesProteinsTextArea
        function TargetPhosphositesProteinsTextAreaValueChanged(app, event)
            target_value = app.TargetPhosphositesProteinsTextArea.Value;
            
        end

        % Selection changed function: InputDataButtonGroup
        function InputDataButtonGroupSelectionChanged(app, event)
             if(app.PhosphositesButton.Value)
                app.PTMsigDBButton.Enable = true;  
                app.PTMsigDBButton.Value = 1;
            end
            if(app.ProteinsButton.Value)
                app.PTMsigDBButton.Enable = false;  
                app.SigDBButton.Value = 1;
            end            
        end

        % Button pushed function: RunKSEAButton
        function RunKSEAButtonPushed(app, event)
            Allphosphosites_FC = app.AllPhosphoSiteslogFoldChangeTextArea.Value;
            for i=1:length(Allphosphosites_FC)
                tmp = Allphosphosites_FC{i};
                if(length(tmp)>2)
                    ind = strfind(tmp,char(9));
                    Allphosphosites{i} = upper(tmp(1:ind-1));
                    FC(i) = str2num(tmp(ind+1:length(tmp))); 
                end
            end
            [KSEA_scores, KSEA_kinases,KSEA_kinases_pval] = KSEA(upper(app.TargetPhosphositesTextArea.Value),Allphosphosites,FC);
            tbl = table(KSEA_kinases,KSEA_scores',KSEA_kinases_pval','VariableNames',{'Kinase_Name','Kinase_Score','Kinase_Pvalue'});
            writetable(tbl,strcat('Result_KinaseSubstrateEnrichmentAnalysis'),'delimiter','\t','WriteVariableNames',true);  
            
            b = barh(app.UIAxes,KSEA_scores);            
            sig = find(KSEA_kinases_pval<0.06);
            hold(app.UIAxes,'on')
            KSEA_kinases(sig) = strcat(KSEA_kinases(sig),'*');     
            b.Parent.YTick = 1:length(KSEA_kinases);
            b.Parent.YTickLabel = KSEA_kinases;
            b.Parent.XLabel.String = {'Kinase Score'; '*: significant kinases'};
            barh(app.UIAxes,sig,KSEA_scores(sig)); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.WelcometoPhosphoEnrichmentLabel = uilabel(app.WelcomeTab);
            app.WelcometoPhosphoEnrichmentLabel.Text = 'Welcome to PhosphoEnrichment!';
            app.ThissoftwareisdevelopedtofaciliatateLabel = uilabel(app.WelcomeTab);
            app.ThissoftwareisdevelopedtofaciliatateLabel.Text = 'PhosphoEnrichment is developed to facilitate the analysis of phosphoproteomics data. ';
            app.PathwayEnrichmentAnalysisLabel = uilabel(app.WelcomeTab);
            app.PathwayEnrichmentAnalysisLabel.Text = 'Pathway Enrichment Analysis';
            app.Label_3 = uilabel(app.WelcomeTab);
            app.Label_3.Text = 'It computes the p-value of the pathways/processes/perturbations that are enriched in the target set at the protein ';
            app.Label_4 = uilabel(app.WelcomeTab);
            app.Label_4.Text = 'and site level using sigDB and PTMsigDB datasets. You should provide the list of all identified phosphosites in the';
            app.Label_5 = uilabel(app.WelcomeTab);
            app.Label_5.Text = 'experiment (which is going to be used as the background population to compute p-value), and the set that you ';
            app.significantsitesorsitesintheidentifiedmodulesetcLabel = uilabel(app.WelcomeTab);
            app.significantsitesorsitesintheidentifiedmodulesetcLabel.Text = 'want to apply enrichment analysis on (it could be a set of significant sites, or sites in the identified modules, etc).';
            app.KinaseSubstrateEnrichmentAnalysisLabel = uilabel(app.WelcomeTab);
            app.KinaseSubstrateEnrichmentAnalysisLabel.Text = 'Kinase Substrate Enrichment Analysis';
            app.Label_6 = uilabel(app.WelcomeTab);
            app.Label_6.Text = 'It identifies the kinases whose targets exhibit significantly altered phosphorylation levels in the given experiment. ';
            app.Label_7 = uilabel(app.WelcomeTab);
            app.Label_7.Text = 'We use the Kinase-Substrate Association provided by PhosphoSitePLUS as a reference dataset/ You should ';
            app.Label_8 = uilabel(app.WelcomeTab);
            app.Label_8.Text = 'provide all identified phosphosites and their fold change, and the set that you want to apply enrichment analysis ';
            app.ContactLabel = uilabel(app.WelcomeTab);
            app.ContactLabel.Text = 'Contact:   ';
            app.Label_9 = uilabel(app.WelcomeTab);
            app.Label_9.Text = 'This software is developed by Marzieh Ayati. If you have any question, contact';
            app.identifiedmodulesetcLabel = uilabel(app.WelcomeTab);
            app.identifiedmodulesetcLabel.Text = 'on (it could be all identified phosphosites in the experiment, or a set of significant sites, or sites in the identified ';
            app.modulesetcLabel = uilabel(app.WelcomeTab);
            app.modulesetcLabel.Text = 'modules, etc). ';
            app.marziehayatiutrgveduLabel = uilabel(app.WelcomeTab);
            app.marziehayatiutrgveduLabel.Text = 'marzieh.ayati@utrgv.edu.';
            app.PathwayEnrichmentAnalysisTab = uitab(app.TabGroup);
            app.PathwayEnrichmentAnalysisTab.Title = 'Pathway Enrichment Analysis';
            app.TargetPhosphositesProteinsTextAreaLabel = uilabel(app.PathwayEnrichmentAnalysisTab);
            app.TargetPhosphositesProteinsTextAreaLabel.Text = 'Target Phosphosites/Proteins';
            app.TargetPhosphositesProteinsTextArea = uitextarea(app.PathwayEnrichmentAnalysisTab);
            app.TargetPhosphositesProteinsTextArea.ValueChangedFcn = createCallbackFcn(app, @TargetPhosphositesProteinsTextAreaValueChanged, true);
            app.TargetPhosphositesProteinsTextArea.Value = {'STAT5B-Y699'; 'MAPK14-T180'; 'MAPK14-Y182'; 'EIF1-Y30'};
            app.AllIdentifiedPhosphositespopulationTextAreaLabel = uilabel(app.PathwayEnrichmentAnalysisTab);
            app.AllIdentifiedPhosphositespopulationTextAreaLabel.Text = 'All Identified Phosphosites (population)';
            app.AllIdentifiedPhosphositespopulationTextArea = uitextarea(app.PathwayEnrichmentAnalysisTab);
            app.AllIdentifiedPhosphositespopulationTextArea.Value = {'PDHA1-S300'; 'PDHA1-Y301'; 'PRDX1-Y10'; 'KDR-Y1212'; 'FLT1-Y1169'; 'FYN-Y185'; 'FYN-Y213'; 'STAT3-Y705'; 'STAT5A-Y694'; 'STAT5B-Y699'; 'MAPK14-T180'; 'MAPK14-Y182'; 'EIF1-Y30'; 'SYK-Y317'};
            app.InputDataButtonGroup = uibuttongroup(app.PathwayEnrichmentAnalysisTab);
            app.InputDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputDataButtonGroupSelectionChanged, true);
            app.InputDataButtonGroup.Title = 'Input Data';
            app.PhosphositesButton = uiradiobutton(app.InputDataButtonGroup);
            app.PhosphositesButton.Text = 'Phosphosites';
            app.PhosphositesButton.Value = true;
            app.ProteinsButton = uiradiobutton(app.InputDataButtonGroup);
            app.ProteinsButton.Text = 'Proteins';
            app.DatabaseButtonGroup = uibuttongroup(app.PathwayEnrichmentAnalysisTab);
            app.DatabaseButtonGroup.Title = 'Database';
            app.PTMsigDBButton = uiradiobutton(app.DatabaseButtonGroup);
            app.PTMsigDBButton.Text = 'PTMsigDB';
            app.PTMsigDBButton.Value = true;
            app.SigDBButton = uiradiobutton(app.DatabaseButtonGroup);
            app.SigDBButton.Text = 'SigDB';
            app.EnrichmentAnalysisButton = uibutton(app.PathwayEnrichmentAnalysisTab, 'push');
            app.EnrichmentAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @EnrichmentAnalysisButtonPushed, true);
            app.EnrichmentAnalysisButton.Text = 'Enrichment Analysis';
            app.ThedetailsofoverlapingproteinssiteswillbewritteninthefileLabel = uilabel(app.PathwayEnrichmentAnalysisTab);
            app.ThedetailsofoverlapingproteinssiteswillbewritteninthefileLabel.Text = 'The details of overlaping proteins/sites will be written in the file.';
            app.ResultPanel = uipanel(app.PathwayEnrichmentAnalysisTab);
            app.ResultPanel.Title = 'Result';
            app.KinaseSubstrateEnrichmentAnalysisTab = uitab(app.TabGroup);
            app.KinaseSubstrateEnrichmentAnalysisTab.Title = 'Kinase Substrate Enrichment Analysis';
            app.AllPhosphoSiteslogFoldChangeTextAreaLabel = uilabel(app.KinaseSubstrateEnrichmentAnalysisTab);
            app.AllPhosphoSiteslogFoldChangeTextAreaLabel.Text = 'All PhosphoSites & log(Fold Change)';
            app.AllPhosphoSiteslogFoldChangeTextArea = uitextarea(app.KinaseSubstrateEnrichmentAnalysisTab);
            app.AllPhosphoSiteslogFoldChangeTextArea.Value = {'PDHA1-S300	-0.86'; 'PDHA1-Y301	1.11'; 'PRDX1-Y10	0.17'; 'KDR-Y1212	0.71'; 'FLT1-Y1169	0.52'; 'FYN-Y185	-0.9'; 'FYN-Y213	0.26'; 'STAT3-Y705	0.63'; 'STAT5A-Y694	0.2'; 'STAT5B-Y699	0.07'; 'MAPK14-T180	-1.78'; 'MAPK14-Y182	-3.07'; 'EIF1-Y30	0.28'; 'SYK-Y317	-0.56'; ''};
            app.TargetPhosphositesTextAreaLabel = uilabel(app.KinaseSubstrateEnrichmentAnalysisTab);
            app.TargetPhosphositesTextAreaLabel.Text = 'Target Phosphosites';
            app.TargetPhosphositesTextArea = uitextarea(app.KinaseSubstrateEnrichmentAnalysisTab);
            app.TargetPhosphositesTextArea.Value = {'STAT5B-Y699'; 'MAPK14-T180'; 'MAPK14-Y182'; 'EIF1-Y30'};
            app.RunKSEAButton = uibutton(app.KinaseSubstrateEnrichmentAnalysisTab, 'push');
            app.RunKSEAButton.ButtonPushedFcn = createCallbackFcn(app, @RunKSEAButtonPushed, true);
            app.RunKSEAButton.Text = 'Run KSEA';
            app.UIAxes = uiaxes(app.KinaseSubstrateEnrichmentAnalysisTab);
            title(app.UIAxes, 'KSEA Result')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.TabdelimitedLabel = uilabel(app.KinaseSubstrateEnrichmentAnalysisTab);
            app.TabdelimitedLabel.Text = '(Tab-delimited)';
            app.TheresultwillbewritteninthefileLabel = uilabel(app.KinaseSubstrateEnrichmentAnalysisTab);
            app.TheresultwillbewritteninthefileLabel.Text = 'The result will be written in the file.';
            app.DataFormatTab = uitab(app.TabGroup);
            app.DataFormatTab.Title = 'Data Format';
            app.PhosphositesLabel = uilabel(app.DataFormatTab);
            app.PhosphositesLabel.Text = 'Phosphosites';
            app.Label = uilabel(app.DataFormatTab);
            app.Label.Text = 'The phosphosites should be given to the program in the format of "Protein-Site":';
            app.MYOTY397Label = uilabel(app.DataFormatTab);
            app.MYOTY397Label.Text = 'MYOT-Y397';
            app.HIPK3S362Label = uilabel(app.DataFormatTab);
            app.HIPK3S362Label.Text = 'HIPK3-S362';
            app.LogFoldChangeLabel = uilabel(app.DataFormatTab);
            app.LogFoldChangeLabel.Text = 'Log(Fold Change)';
            app.Label_2 = uilabel(app.DataFormatTab);
            app.Label_2.Text = 'For KSEA analysis, you should provide the log(fold change) of the phosphosites. It should be tab-delimited:';
            app.MYOTT397215Label = uilabel(app.DataFormatTab);
            app.MYOTT397215Label.Text = 'MYOT-T397         -2.15';
            app.HIPK3S3983124Label = uilabel(app.DataFormatTab);
            app.HIPK3S3983124Label.Text = 'HIPK3-S398         3.124';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhosphoEnrichment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end